classdef PlayerTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlayerUIFigure              matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        PlayerNum_Label             matlab.ui.control.Label
        LeftPanelBackground_Image   matlab.ui.control.Image
        RightPanel                  matlab.ui.container.Panel
        Start_Panel                 matlab.ui.container.Panel
        EnterGameID_TextField       matlab.ui.control.NumericEditField
        WelcomeText_Label           matlab.ui.control.Label
        StartBackground_Image       matlab.ui.control.Image
        Options_Panel               matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        Title_Label                 matlab.ui.control.Label
        NumPlayers_Label            matlab.ui.control.Label
        NumPlayers_Dropdown         matlab.ui.control.DropDown
        NumRounds_Label             matlab.ui.control.Label
        NumRounds_Dropdown          matlab.ui.control.DropDown
        FillWithAI_Label            matlab.ui.control.Label
        FillWithAI_Checkbox         matlab.ui.control.CheckBox
        OptionsBackground_Image     matlab.ui.control.Image
        EnterName_Panel             matlab.ui.container.Panel
        EnterName_TextField         matlab.ui.control.EditField
        EnterNameBackground_Image   matlab.ui.control.Image
        Tutorial_Panel              matlab.ui.container.Panel
        Tutorial_Label              matlab.ui.control.Label
        TutorialsBackground_Image   matlab.ui.control.Image
        Game_Panel                  matlab.ui.container.Panel
        DisableAnimations_Label     matlab.ui.control.Label
        DisableAnimations_Checkbox  matlab.ui.control.CheckBox
        GameBackground_Image        matlab.ui.control.Image
        End_Panel                   matlab.ui.container.Panel
        EndBackground_Image         matlab.ui.control.Image
        UXFLow                      matlab.ui.container.Tree
        Host                        matlab.ui.container.TreeNode
        hostGame_1                  matlab.ui.container.TreeNode
        setOptions_2                matlab.ui.container.TreeNode
        setNumPlayers_2_1           matlab.ui.container.TreeNode
        setNumRounds_2_2            matlab.ui.container.TreeNode
        continueToEnterName_2_3     matlab.ui.container.TreeNode
        enterName_3                 matlab.ui.container.TreeNode
        enterName_3_1               matlab.ui.container.TreeNode
        writeGameData_3_2           matlab.ui.container.TreeNode
        hostID_3_2a                 matlab.ui.container.TreeNode
        numPlayersNode_3_2b         matlab.ui.container.TreeNode
        numRoundsNode_3_2c          matlab.ui.container.TreeNode
        hostPlayerNameNode_3_2d     matlab.ui.container.TreeNode
        gototutorial_3_3            matlab.ui.container.TreeNode
        tutorial_4                  matlab.ui.container.TreeNode
        gameIDDisplayed_4_1         matlab.ui.container.TreeNode
        User                        matlab.ui.container.TreeNode
        joinGame_1                  matlab.ui.container.TreeNode
        enterGameID_1a              matlab.ui.container.TreeNode
        invalidGameID_to_1a         matlab.ui.container.TreeNode
        validGameID_to_2            matlab.ui.container.TreeNode
        cancel_1b                   matlab.ui.container.TreeNode
        cancelButtonPushed_to_1     matlab.ui.container.TreeNode
        enterName_2                 matlab.ui.container.TreeNode
        Node3_2                     matlab.ui.container.TreeNode
        Node4_2                     matlab.ui.container.TreeNode
        Node5_2                     matlab.ui.container.TreeNode
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %% custom components properties
    properties (Access = public)
        ScoreBoard                              matlab.ui.container.Panel
        GameBoard                               matlab.ui.container.Panel
        LeaderBoard                             matlab.ui.container.Panel
        ScoreTable                              matlab.ui.control.Table
        SavedDiceTable                          matlab.ui.control.Table
        DetailedRollDisplay                     matlab.ui.control.Table
        DiceGrid                                matlab.ui.container.GridLayout
        LeaderBoardGrid                         matlab.ui.container.GridLayout
        RollDice_Button                         matlab.ui.control.Button
        SaveDice_Button                         matlab.ui.control.Button
    end
    
    %% thingspeak properties
    properties (Access = public)
        gameID;
        numPlayers;
        numRounds
        playerID;

        channelID = 1945003;
        channelIDDice = 1945022;

        writeKey = 'RIB6JG6D34YM1BIQ';
        readKey = 'T9G3ZHI6RZ7KTOHB';

        writeKeyDice = '7BGI8AFJZYYJ8EED';
        readKeyDice = 'V6LE1Y58XD8ZX7WR';

        hostPlayerIDField = 1;
        numPlayersField = 2;
        numRoundsField = 3;
        playerNameField = 4;
    end
    
    %% main properties
    properties (Access = public)
        % player information
        currentPlayer;
        playerNames = {};
        playerArray = repmat(player(), 1, 4);
        isAI = false(1, 4);
        
        % component arrays
        dice = repmat(uiimage(), 6);
        buttons = repmat(uibutton(), 10);
        labels = repmat(uilabel(), 8, 4);
        images = repmat(uiimage(), 4);

        % component position arrays
        boardPositions = repmat([0 0 0 0], 1, 4);
        rollDiceButtonPositions = repmat([0 0 0 0], 1, 4);
        saveDiceButtonPositions = repmat([0 0 0 0], 1, 4);
        buttonPositions = repmat([0 0 0 0], 1, 10);
        labelPositions = zeros(8, 4, 4);

        % component data arrays
        scoreTableRowNames = [];
        playerLabels = [];
        AILabels = [];
        playerNameLabels = [];
        labelText5 = [];
        labelText6 = [];
        labelText7 = [];
        labelText8 = [];
        fontSizes = zeros(8, 4);
        diceStatus = false(1, 6);
        toggleOptions = zeros(3, 2);
        
        % image file names
        diceImages;
        placeImages;

        % data
        AIMode = false;
        currentRound; 
        roundScores = zeros(10, 4);
        finalScore = zeros(1, 4);
    end

    %% custom component creation
    methods
        % set component and game data
        function initializeData(app)
            % set image filenames
            app.diceImages = ...
                ["diceFace1.png", "diceFace2.png", "diceFace3.png"...
                "diceFace4.png", "diceFace5.png", "diceFace6.png"...
                "diceFace1_Selected.png", "diceFace2_Selected.png"...
                "diceFace3_Selected.png", "diceFace4_Selected.png"...
                "diceFace5_Selected.png", "diceFace6_Selected.png"];
            app.placeImages =...
                 ["FirstPlace.jpg", "SecondPlace.jpg",...
                "ThirdPlace.jpg", "FourthPlace.jpg"];

            % component position data
            app.boardPositions =...
                [15 360 180 30; 225 360 180 30;...
                15 120 180 30; 225 120 180 30];
            app.rollDiceButtonPositions =...
                [55 320 100 20; 265 320 100 20;...
                55 80 100 20; 265 80 100 20];
            app.saveDiceButtonPositions =...
                [55 280 100 20; 265 280 100 20;...
                55 40 100 20; 265 40 100 20];
            app.buttonPositions =...
                [140 280 140 40; 140 220 140 40; 140 160 140 40;...%.......HOST, HOST LOCAL, JOIN        
                220 80 50 30; 180 50 60 20; 150 60 120 30;...%.............CONFIRM GAME ID, CANCEL JOIN, CONFIRM OPTIONS
                150 25 120 25; 160 140 100 25; 160 140 100 25;...%.........CANCEL HOST, HOST READY, PLAYER READY
                160 60 100 40; 55 320 100 20; 55 280 100 20];%.............PLACEHOLDER, ROLL DICE, SAVE DICE
            labelPositions1 =... % player labels
                [0 430 210 30; 210 430 210 30; 0 190 210 30; 210 190 210 30];
            labelPositions2 =... % player name labels
                [0 400 210 30; 210 400 210 30; 0 160 210 30; 210 160 210 30];
            labelPositions5 =... % CURRENTLY SAVED DICE, DETAILED ROLL DISPLAY, PLACEHOLDER, PLACEHOLDER
                [0 34 220 30; 0 110 220 30; 0 0 0 0; 0 0 0 0];
            labelPositions6 =... % player join status labels
                [0 380 220 30; 0 340 220 30; 0 300 220 30; 0 260 220 30];
            labelPositions7 =... % GAME CODE, WAITING, PLACEHOLDER, PLACEHOLDER
                [0 180 400 36; 0 120 420 28; 0 0 0 0; 0 0 0 0];
            labelPositions8 =... % ENTERNAME, ENTER GAME ID
                [0 300 420 50; 0 110 420 30; 0 300 420 50; 0 300 420 50];

            app.labelPositions = labelPositions1;
            app.labelPositions(:, :, 2) = labelPositions2;
            app.labelPositions(:, :, 5) = labelPositions5;
            app.labelPositions(:, :, 6) = labelPositions6;
            app.labelPositions(:, :, 7) = labelPositions7;
            app.labelPositions(:, :, 8) = labelPositions8;

            % component data presets
            app.playerNames =...
                {'P1'; 'P2'; 'P3'; 'P4'};
            app.scoreTableRowNames =...
                {'Round 1'; 'Round 2'; 'Round 3'; 'Round 4'; 'Round 5';...
                'Round 6'; 'Round 7'; 'Round 8'; 'Round 9'; 'Round 10'};
            app.playerLabels =...
                {'Player 1'; 'Player 2'; 'Player 3'; 'Player 4'};
            app.AILabels = ...
                {'AI 1'; 'AI 2'; 'AI 3'; 'AI 4'};
            app.labelText5 =...
                {'Currently Saved Dice:';...
                sprintf(['Rolls for Selected Round:\n'...
                '(Select a Score on the Scoreboard)']);...
               ''; ''};
            app.labelText6 =...
               {'Player 1 - Waiting...'; 'Player 2 - Waiting...';...
               'Player 3 - Waiting...'; 'Player 4 - Waiting...'};
            app.labelText7 =...
               [{''}, {'Waiting For Players...'} ,...
               {''}, {''}];
            app.labelText8 =...
               {'Enter Your Name:'; 'Enter Game ID:'; ''; ''};
            app.fontSizes =...
                [18 18 18 18; 16 16 16 16; 24 24 24 24; 24 24 24 24;...
                12 12 10 10; 16 16 16 16; 26 20 10 10; 36 16 36 36];

            app.toggleOptions =...
                ["on", "on"; "on", "off"; "off", "off"];

            app.currentPlayer = 1;
            app.currentRound = 1;
        end

        % custom component creation
        function initializeCustomComponents(app)
            % Boards
            app.GameBoard = uipanel(app.Game_Panel);                       % gameboard
            app.ScoreBoard = uipanel(app.LeftPanel);                       % scoreboard
            app.LeaderBoard = uipanel(app.RightPanel);                     % leaderboard

            app.DiceGrid = uigridlayout(app.GameBoard);                    % gameboard grid
            app.LeaderBoardGrid = uigridlayout(app.LeaderBoard);           % leaderboard grid
            
            app.ScoreTable = uitable(app.ScoreBoard);                      % scoreboard table
            app.SavedDiceTable = uitable(app.ScoreBoard);                  % saved dice table
            app.DetailedRollDisplay = uitable(app.ScoreBoard);             % detailed roll table            
        end

        % initialize component properties
        function initializeComponentProperties(app)
            app.GameBoard.Position = app.boardPositions(1, :);             % gameboard
            app.ScoreBoard.Position = [0 0 220 480];                       % scoreboard
            app.LeaderBoard.Position = [0 0 420 480];                      % leaderboard

            app.DiceGrid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x', '1x'};
            app.DiceGrid.RowHeight = {'1x'};
            app.DiceGrid.RowSpacing = 0;
            app.DiceGrid.ColumnSpacing = 0;
            app.DiceGrid.Padding = [0 0 0 0];
            app.DiceGrid.BackgroundColor = [0, 0, 0];

            app.LeaderBoardGrid.ColumnWidth = {'1x', '2x', '1x'};
            app.LeaderBoardGrid.RowSpacing = 0;
            app.LeaderBoardGrid.ColumnSpacing = 0;
            app.LeaderBoardGrid.Padding = [0 0 0 0];
            
            app.ScoreTable.Position = [0 40 220 440];                      % score table
            app.ScoreTable.RowName = app.scoreTableRowNames(1, :);
            app.ScoreTable.ColumnName = {'P1'; 'P2'; 'P3'; 'P4'};
            app.ScoreTable.ColumnWidth = {39, 39, 39, 39};
            
            app.SavedDiceTable.Position = [0 0 220 30];                    % saved dice table
            app.SavedDiceTable.RowName = {};
            app.SavedDiceTable.ColumnName = {};
            app.SavedDiceTable.ColumnWidth = {36.6, 36.6, 36.6, 36.6, 36.6, 36.6};

            app.DetailedRollDisplay.Position = [0 80 220 30];              % detailed roll display
            app.DetailedRollDisplay.RowName = {};
            app.DetailedRollDisplay.ColumnName = {};
            app.DetailedRollDisplay.ColumnWidth = {36.6, 36.6, 36.6, 36.6, 36.6, 36.6};

            % interactivity
            app.ScoreBoard.Visible = 'off';
            app.ScoreTable.Multiselect = 'off';
            app.LeaderBoard.Visible = 'off';

            % callbacks
            app.ScoreTable.SelectionChangedFcn = createCallbackFcn(app, @expandScoreInformation, false);
        end

        % create component arrays
        function createComponentArrays(app)
            delete(app.dice(1).Parent);
            delete(app.buttons(1).Parent);
            delete(app.labels(1).Parent);
            delete(app.images(1).Parent);

            % dice
            for i = 1:6
                app.dice(i) = uiimage(app.DiceGrid,...
                    "ImageClickedFcn", {@app.diceClicked, i});
                app.dice(i).ImageSource = app.diceImages(i);
                app.dice(i).ScaleMethod = 'fill';
                app.dice(i).Layout.Row = 1;
                app.dice(i).Layout.Column = i;
            end

            % buttons
            % generate host game button
            app.buttons(1) = uibutton(app.Start_Panel, 'push', 'ButtonPushedFcn', @app.hostGame);
            app.buttons(1).Text = 'Host Game';
            
            % gnenerate host local game button
            app.buttons(2) = uibutton(app.Start_Panel, 'push', 'ButtonPushedFcn', @app.hostLocalGame);
            app.buttons(2).Text = 'Host Local Game';

            % generate join game button
            app.buttons(3) = uibutton(app.Start_Panel, 'push', 'ButtonPushedFcn', @app.joinGame);
            app.buttons(3).Text = 'Join Game';

            % generate confirm game id button
            app.buttons(4) = uibutton(app.Start_Panel, 'push', 'ButtonPushedFcn', @app.confirmGameID);
            app.buttons(4).Text = '';
            app.buttons(4).Icon = 'ArrowIcon.png';

            % generate cancel join
            app.buttons(5) = uibutton(app.Start_Panel, 'push', 'ButtonPushedFcn', @app.cancelJoin);
            app.buttons(5).Text = 'Cancel';

            % generate confirm options
            app.buttons(6) = uibutton(app.Options_Panel, 'push', 'ButtonPushedFcn', @app.confirmOptions);
            app.buttons(6).Text = 'Confirm Options';

            % generate cancel host
            app.buttons(7) = uibutton(app.Options_Panel, 'push', 'ButtonPushedFcn', @app.cancelHost);
            app.buttons(7).FontColor = [1 0 0];
            app.buttons(7).Text = 'Cancel';
            
            % generate host ready button
            app.buttons(8) = uibutton(app.EnterName_Panel, 'push', 'ButtonPushedFcn', @app.hostReady);
            app.buttons(8).Text = 'Submit Name';

            % generate player ready button
            app.buttons(9) = uibutton(app.EnterName_Panel, 'push', 'ButtonPushedFcn', @app.playerReady);
            app.buttons(9).Text = 'Submit Name';

            % generate PLACEHOLDER
            app.buttons(10) = uibutton(app.Tutorial_Panel, 'push', 'ButtonPushedFcn', @app.Ready_ButtonPushed);
            app.buttons(10).Visible = 'off';

            % generate roll dice button
            app.buttons(11) = uibutton(app.Game_Panel, 'push', 'ButtonPushedFcn', @app.rollDice);
            app.buttons(11).Text = 'Roll Dice';

            % generate save dice button
            app.buttons(12) = uibutton(app.Game_Panel, 'push', 'ButtonPushedFcn', @app.saveDice);
            app.buttons(12).Text = 'Save Dice';

            % set button properties
            for i = 1:12
                app.buttons(i).IconAlignment = 'center';
                app.buttons(i).FontName = 'Palatino';
                app.buttons(i).Position = app.buttonPositions(i, :);
            end

            for i = 4:12
                toggleComponent(app, 'button', i, 3);
            end

            % Labels
            % initialize labels
            for j = 1:4
                app.labels(1, j) = uilabel(app.Game_Panel);                % player labels
                app.labels(2, j) = uilabel(app.Game_Panel);                % player name labels
                app.labels(3, j) = uilabel(app.LeaderBoardGrid);           % leaderboard name labels
                app.labels(4, j) = uilabel(app.LeaderBoardGrid);           % leaderboard score labels
                app.labels(5, j) = uilabel(app.ScoreBoard);                % scoreboard labels
                app.labels(6, j) = uilabel(app.LeftPanel);                 % player join status labels
                app.labels(7, j) = uilabel(app.Tutorial_Panel);            % tutorial screen labels
                
            end
            app.labels(8, 1) = uilabel(app.EnterName_Panel);               % enter name label
            app.labels(8, 2) = uilabel(app.Start_Panel);                   % enter game id label
            app.labels(8, 3) = uilabel(app.Start_Panel);
            app.labels(8, 4) = uilabel(app.Start_Panel);
            
            % properties that apply to all labels
            for i = 1:8
                for j = 1:4
                    app.labels(i, j).HorizontalAlignment = 'center';
                    app.labels(i, j).FontColor = [1 0 1]; 
                    app.labels(i, j).Position = app.labelPositions(j, :, i);
                    app.labels(i, j).FontSize = app.fontSizes(i, j);
                end
            end

            % properties that apply to specific labels
            for j = 1:4
                app.labels(3, j).Layout.Row = j;
                app.labels(3, j).Layout.Column = 2;
                app.labels(4, j).Layout.Row = j;
                app.labels(4, j).Layout.Column = 3;

                app.labels(5, j).Text = app.labelText5(j);
                app.labels(6, j).Text = app.labelText6(j);
                app.labels(7, j).Text = app.labelText7(j);
                app.labels(8, j).Text = app.labelText8(j);
                
                app.labels(6, j).Visible = 'off';
                
                for i = 2:8
                    app.labels(i, j).FontWeight = 'bold'; 
                end
            end
            app.labels(8, 2).Visible = 'off';
            app.labels(1, 1).FontColor = [1 1 0];
            app.labels(2, 1).FontColor = [1 1 0];
            
            % images
            for i = 1:4
                app.images(i) = uiimage(app.LeaderBoardGrid);
                app.images(i).ImageSource = app.placeImages(i);
                app.images(i).Layout.Row = 1;
                app.images(i).Layout.Column = i;
            end
        end

        % populate scoreboard
        function populateScoreBoard(app, numPlayers, numRounds)
            if app.AIMode
                app.ScoreTable.Data = zeros(numRounds, 4);
                app.ScoreTable.ColumnName = app.playerNames(1:4);
            else
                app.ScoreTable.Data = zeros(numRounds, numPlayers);
                app.ScoreTable.ColumnName = app.playerNames(1:numPlayers);
            end
                app.ScoreTable.RowName = app.scoreTableRowNames(1:numRounds);
                app.DetailedRollDisplay.Data = zeros(1, 6);
                app.SavedDiceTable.Data = zeros(1, 6);
            
            app.ScoreBoard.Visible = 'on';
        end

        % populate player information
        function populatePlayerLabels(app, numPlayers)            
            % set label indicating current player
            app.labels(1, app.playerID).Text = "YOU";
            app.labels(1, app.playerID).FontWeight = 'bold';
            app.labels(1, app.playerID).FontWeight = 'bold';
            
            % populate player labels
            for i = 1:numPlayers
                app.labels(1, i).Text = app.playerLabels(i);
                app.labels(2, i).Text = app.playerNames(i);
            end
        end

        % reset all custom components for a new turn
        function resetComponents(app)
            % reset dice
            for i = 1:6
                app.dice(i).ImageSource = app.diceImages(i);
                app.dice(i).Visible = 'on';
                app.diceStatus(i) = false;
            end

            % reset dice grid
            app.DiceGrid.RowHeight = {'1x'};
            app.DiceGrid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x', '1x'};
            app.DiceGrid.ColumnSpacing = 0;
            app.DiceGrid.RowSpacing = 0;
            app.DiceGrid.Padding = [0 0 0 0];
            
            % reset saved dice display on scoreboard
            app.SavedDiceTable.Data = {0, 0, 0, 0, 0, 0};
        end

        % update player labels
        function updatePlayerLabels(app)
            if app.currentPlayer == 1               
                app.labels(1, 1).FontColor = [1 1 0];
                app.labels(2, 1).FontColor = [1 1 0];
                app.labels(1, 4).FontColor = [1 0 1];
                app.labels(2, 4).FontColor = [1 0 1];
            else
                app.labels(1, app.currentPlayer - 1).FontColor = [1 0 1];
                app.labels(2, app.currentPlayer - 1).FontColor = [1 0 1];
                app.labels(1, app.currentPlayer).FontColor = [1 1 0];
                app.labels(2, app.currentPlayer).FontColor = [1 1 0];
            end
        end

        % create AI players
        function createAIPlayers(app, numPlayers)
            for i = numPlayers + 1:4
                app.playerArray(i) = player(app.gameID, i, "AI");
                app.labels(1, i).Text = app.AILabels(i);
                app.labels(2, i).Text = 'AI';
                app.isAI(i) = true;
            end
        end

        % enable dice selection
        function enableDiceSelection(app)
            for i = 1:6
                app.dice(i).Enable = 'on';
            end
        end

        % disable dice selection
        function disableDiceSelection(app)
            for i = 1:6
                app.dice(i).Enable = 'off';
            end
        end

        % dice image is clicked
        function diceClicked(app, src, ~, i)
            val = app.playerArray(app.currentPlayer).currentDice(i);

            if ~app.diceStatus(i)
                src.ImageSource = app.diceImages(val + 6);
            else
                src.ImageSource = app.diceImages(val);
            end
            app.diceStatus(i) = ~app.diceStatus(i);

            % enables save dice button if at least one dice is selected
            if sum(app.diceStatus) ~= 0
                toggleComponent(app, 'button', 12, 1); % save dice ON
            else
                toggleComponent(app, 'button', 12, 2); % save dice INACTIVE
            end
        end
    end

    %% functions
    methods
        function toggleComponent(app, type, varargin)
            % 1 = 'on', 'on' | 2 = 'on', 'off' | 3 = 'off', 'off'
            switch type
                case 'button' % id, option
                    app.buttons(varargin{1}).Visible = app.toggleOptions(varargin{2}, 1);
                    app.buttons(varargin{1}).Enable = app.toggleOptions(varargin{2}, 2);
                case 'label' % id1, id2, option
                    app.labels(varargin{1}, varargin{2}).Visible = app.toggleOptions(varargin{3}, 1);
                    app.labels(varargin{1}, varargin{2}).Enable = app.toggleOptions(varargin{3}, 2);
            end
        end

        % change scene
        function changeScene(app, src, dest)
            switch dest
                case 'start'
                    app.Start_Panel.Visible = 'on';
                    app.Start_Panel.Enable = 'on';
                case 'options'
                    app.Options_Panel.Visible = 'on';
                    app.Options_Panel.Enable = 'on';
                case 'name'
                    app.EnterName_Panel.Visible = 'on';
                    app.EnterName_Panel.Enable = 'on';
                case 'tutorial'
                    app.Tutorial_Panel.Visible = 'on';
                    app.Tutorial_Panel.Enable = 'on';
                case 'game'
                    app.Game_Panel.Visible = 'on';
                    app.Game_Panel.Enable = 'on';
                case 'end'
                    app.End_Panel.Visible = 'on';
                    app.End_Panel.Enable = 'on';
            end

            switch src
                case 'start'
                    app.Start_Panel.Visible = 'off';
                    app.Start_Panel.Enable = 'off';
                case 'options'
                    app.Options_Panel.Visible = 'off';
                    app.Options_Panel.Enable = 'off';
                case 'name'
                    app.EnterName_Panel.Visible = 'off';
                    app.EnterName_Panel.Enable = 'off';
                case 'tutorial'
                    app.Tutorial_Panel.Visible = 'off';
                    app.Tutorial_Panel.Enable = 'off';
                case 'game'
                    app.Game_Panel.Visible = 'off';
                    app.Game_Panel.Enable = 'off';
            end
        end
    end
    
    %% host flow
    methods
        % host local game button pushed
        function hostLocalGame(app, ~, ~)
            % go to options panel
            changeScene(app, 'start', 'options');

            app.gameID = -1;
            toggleComponent(app, 'button', 7, 1); % cancel host ON
        end

        % host game button pushed
        function hostGame(app, ~, ~)
            % go to options panel
            changeScene(app, 'start', 'options');

            toggleComponent(app, 'button', 6, 2); % confirm options INACTIVE
            toggleComponent(app, 'button', 7, 1); % cancel host ON
        end

        % continue to enter name button pushed
        function confirmOptions(app, ~, ~, ~)
            % go to enter name
            changeScene(app, 'options', 'name');
            app.AIMode = app.FillWithAI_Checkbox.Value; 

            toggleComponent(app, 'label', 8, 1, 1); % enter name label ON
            toggleComponent(app, 'button', 8, 1); % host submit name ON
        end

        % cancel host button pushed
        function cancelHost(app, ~, ~, ~)
            % go back to start
            changeScene(app, 'options', 'start');

            toggleComponent(app, 'button', 6, 3); % confirm options OFF
            toggleComponent(app, 'button', 7, 3); % cancel host OFF
        end

        % submit name button pushed
        function hostReady(app, ~, ~, ~)
            changeScene(app, 'name', 'tutorial');
            toggleComponent(app, 'label', 6, 1, 1); % player 1 join status ON

            % set current player data
            app.playerID = app.currentPlayer;
            playerName = app.EnterName_TextField.Value;
            app.numPlayers = str2double(app.NumPlayers_Dropdown.Value);
            app.numRounds = str2double(app.NumRounds_Dropdown.Value);

            for i = 2:app.numPlayers
                toggleComponent(app, 'label', 6, i, 1);
            end

            % generate game ID
            ID = app.generatePlayerID();
            app.labels(7, 1).Text = compose('Generating Game ID...');

            % create host (p1) player object
            p1 = player(ID, app.playerID, playerName, app.numPlayers,...
                app.numRounds, app.FillWithAI_Checkbox.Value);
            app.playerArray(app.playerID) = p1;

            changeScene(app, 'name', 'tutorial');

            % skip thingspeak if local game
            if app.gameID == -1
                populatePlayerLabels(app, app.numPlayers);
                createAIPlayers(app, app.numPlayers); 
                populateScoreBoard(app, app.numPlayers, app.numRounds);
                disableDiceSelection(app);
                toggleComponent(app, 'button', 11, 1);
                toggleComponent(app, 'button', 12, 2);
                return;
            end

            app.labels(6, 1).Text = 'Player 1 - Connecting...';
            app.labels(6, 1).FontColor = [1 1 0];

            app.gameID = ID;
            % write game data to thingspeak
            thingSpeakWrite(app.channelID,...
                [app.gameID, app.numPlayers, app.numRounds, p1.playerName],...
                'WriteKey', p1.writeKey);
            pause(1);

            % display game ID for host to share with other players 
            
            app.labels(6, 1).FontColor = [0 1 0];
            app.labels(6, 1).Text = compose("%s - CONNECTED", playerName);
            app.labels(7, 1).Text =  compose('Your Game ID is: %d', app.gameID);

            % host begins a while loop that executes for each remaining
            % player. When a new player is detected, host saves the
            % data and replaces the original data again
            for i = 2:app.numPlayers
                    newPlayerFlag = app.gameID;
                    while newPlayerFlag == app.gameID
                        app.labels(6, i).Text = compose('Player %d - Waiting.  ', i);
                        pause(.25);
                        app.labels(6, i).Text = compose('Player %d - Waiting.. ', i);
                        pause(.25);
                        app.labels(6, i).Text = compose('Player %d - Waiting...', i);

                        Data = thingSpeakRead(app.channelID,...
                        'ReadKey', app.readKey,...
                        'OutputFormat', 'table');
                        pause(1);
                        newPlayerFlag = Data.gameID(1);
                    end
                    % new player detected and while loop is exited
                    % save new player data
                    newPlayerName = Data.playerName(1);
                    app.playerNames(i) = newPlayerName;
                    app.playerArray(i) = player(app.gameID, newPlayerName);
                    app.playerArray(i).playerID = i;

                    app.labels(6, i).FontColor = [1 1 0];
                    app.labels(6, i).Text = compose('%s - Connecting.  ', string(newPlayerName));
                    app.labels(6, i).Text = compose('%s - Connecting.. ', string(newPlayerName));
                    app.labels(6, i).Text = compose('%s - Connecting...', string(newPlayerName));
                    
                    % quickly replace data so the next player does not
                    % encounter any issues
                    thingSpeakWrite(app.channelID,...
                    [app.gameID, app.numPlayers, app.numRounds,...
                    p1.playerName], 'WriteKey', p1.writeKey);
                    app.labels(6, i).FontColor = [0 1 0];
                    app.labels(6, i).Text = compose('%s - CONNECTED', string(newPlayerName));
                    pause(1);
            end
            % once all players have joined, host will write all player
            % data. The final set of data will indicate how many data
            % points players must read
            app.labels(7, 2).FontColor = [0 1 0];
            app.labels(7, 2).Text = 'Sending Game Data...';
            for i = 1:app.numPlayers
                thingSpeakWrite(app.channelID,...
                [0, 0, 0, string(app.playerArray(i).playerName)],...
                'WriteKey', p1.writeKey);
                pause(1);
            end
            thingSpeakWrite(app.channelID,...
                [-1, app.numPlayers, app.numRounds, app.AIMode],...
                'WriteKey', p1.writeKey);
            pause(5);

            % HOST BEGINS PLAY
            populatePlayerLabels(app, app.numPlayers);
            createAIPlayers(app, app.numPlayers);
            populateScoreBoard(app, app.numPlayers, app.numRounds);
            disableDiceSelection(app);
            toggleComponent(app, 'button', 11, 1);
            toggleComponent(app, 'button', 12, 2);

            for i = 1:app.numPlayers
                toggleComponent(app, 'label', 6, i, 3);
            end

            % host writes game start flag for dice, so no one is confused
            thingSpeakWrite(app.channelIDDice, -1,...
                'WriteKey', app.writeKeyDice);
            pause(5);

            changeScene(app, 'tutorial', 'game');
        end
    end

    %% player flow
    methods (Access = private)
        % join game button pushed
        function joinGame(app,  ~, ~)
            % update components
            toggleComponent(app, 'button', 1, 2); % host game INACTIVE
            toggleComponent(app, 'button', 2, 2); % host local game INACTIVE
            toggleComponent(app, 'button', 3, 2); % join game INACTIVE
            toggleComponent(app, 'button', 4, 1); % confirm game id ON
            toggleComponent(app, 'button', 5, 1); % cancel join ON

            toggleComponent(app, 'label', 8, 2, 1);
            app.EnterGameID_TextField.Visible = 'on';
            app.EnterGameID_TextField.Enable = 'on';
        end

        % cancel join button pushed
        function cancelJoin(app,  ~, ~)
            % update components
            toggleComponent(app, 'label', 8, 2, 3);
            app.EnterGameID_TextField.Visible = 'off';
            app.EnterGameID_TextField.Enable = 'off';
            toggleComponent(app, 'button', 1, 1); % host game ON
            toggleComponent(app, 'button', 2, 1); % host local game ON
            toggleComponent(app, 'button', 3, 1); % join game ON
            toggleComponent(app, 'button', 4, 3); % confirm game id OFF
            toggleComponent(app, 'button', 5, 3); % cancel join OFF
        end

        % confirm game id button pushed
        function confirmGameID(app, ~, ~)
            toggleComponent(app, 'button', 4, 2); % confirm game id INACTIVE
            
            % read host information from thingspeak
            hostDataTable = thingSpeakRead(app.channelID,...
                'ReadKey', app.readKey,...
                outputFormat = 'table');
            app.gameID = hostDataTable.gameID(1);

            % check if entered ID matches host ID
            if app.EnterGameID_TextField.Value == app.gameID || app.playerTest == true
                app.labels(8, 2).FontColor = [0 1 0];
                app.labels(8, 2).Text = "Success!";
                pause(1);
                changeScene(app, 'start', 'name');
                toggleComponent(app, 'button', 9, 1); % player ready ON
            else
                app.labels(8, 2).FontColor = [1 0 0];
                app.labels(8, 2).Text = "Game Not Found";
                pause(1);
                app.labels(8, 2).FontColor = [1 0 1];
                app.labels(8, 2).Text = "Enter Game ID:";
                toggleComponent(app, 'button', 4, 1); % confirm game id ON
            end
        end

        % submit name button pushed by player
        function playerReady(app, ~, ~)
            % create thingspeak write data
            newPlayerFlag = 0;
            newPlayerName = app.EnterName_TextField.Value;
            newPlayer = player(app.gameID, newPlayerName);

            % new players will write their data 
            thingSpeakWrite(app.channelID,...
                [newPlayerFlag, 0, 0, string(newPlayerName)],...
                'WriteKey', newPlayer.writeKey);

            % go to tutorial
            changeScene(app, 'name', 'tutorial');

            readyFlag = -1;
            while readyFlag ~= -1
                app.labels(7, 2).Text = 'Connecting to Host. ';
                pause(.25);
                app.labels(7, 2).Text = 'Connecting to Host..';
                pause(.25);
                app.labels(7, 2).Text = 'Connecting to Host..';
                pause(1);
                Data = thingSpeakRead(app.channelID,...
                    'ReadKey', app.readKey,...
                    'OutputFormat', 'table');
                    readyFlag = Data.gameID(1);
            end
            % all players are ready and players read all data from post
            app.labels(7, 2).FontColor = [0 1 0];
            app.labels(7, 2).Text = 'Connection Established!';
            pause(2);
            app.labels(7, 2).Text = 'Watiting for Host Data...';
            pause(8);

            Data = thingSpeakRead(app.channelID,...
                'ReadKey', app.readKey,...
                'OutputFormat', 'table');
                pause(1);
                app.numPlayers = Data.numPlayers(1);
                app.numRounds = Data.numRounds(1);
                app.AIMode = Data.playerName(1);
                pause(1);

            Data = thingSpeakRead(app.channelID,...
                'NumPoints', app.numPlayers,...
                'ReadKey', app.readKey,...
                'OutputFormat', 'table');
                pause(1);

            for i = 1:app.numPlayers
               app.playerNames(i) = Data.playerName(i);
               app.playerArray(i) = player(app.gameID, Data.playerName);
               if ismember(newPlayer.playerName, Data.playerName)
                   newPlayer.playerID = i;
                   app.playerID = i;
               end

               toggleComponent(app, 'label', 6, i, 1);
               app.labels(6, i).FontColor = [0 1 0];
               app.labels(6, i).Text = compose('%s - CONNECTED', string(app.playerNames(i)));
            end
            pause(2);

            app.currentPlayer = 1;
            populatePlayerLabels(app, app.numPlayers);
            createAIPlayers(app, app.numPlayers);
            populateScoreBoard(app, app.numPlayers, app.numRounds);
            disableDiceSelection(app);

            for i = 1:app.numPlayers
                toggleComponent(app, 'label', 6, i, 3);
            end

            changeScene(app, 'tutorial', 'game');

            % players wait for first turn to be over
            waitForTurn(app, app.playerID);            
        end

        
    end

    %% core ux flow
    methods
        function waitForTurn(app, playerID)
            % stagger pauses
            pause(playerID);

            turnDone = -1;
            while turnDone == -1
                Data = thingSpeakRead(app.channelIDDice,...
                'ReadKey', app.readKeyDice,...
                'OutputFormat', 'table');
                turnDone = Data.playerID(1);
                pause(3);
            end

            % player has ended their turn
            nextTurn(app, Data)
        end

        function nextTurn(app, Data)
            finalHand = Data{2, 7};
            score = calculcateScore(app, finaHand);
            app.playerArray(app.currentPlayer).savedTurns(app.currentRound, :) = finalHand;
            app.roundScores(app.currentRound, app.currentPlayer) = score;

            % if it is this player's turn, flag is sent back to thingspeak
            % and roll dice button is enabled
            ID = nextPlayer(app);
            if ID == app.playerID
                thingSpeakWrite(app.channelIDDice,...
                    -1, 'WriteKey', app.writeKeyDice);
                toggleComponent(app, 'button', 11, 1);
                toggleComponent(app, 'button', 12, 2);
            else
                waitForTurn(app, app.playerID);
            end
            
        end

        % roll dice button pushed
        function rollDice(app, ~, ~, ~)
            toggleComponent(app, 'button', 11, 2); % roll dice OFF
            
            % determine number of dice to roll and generate rolls
            numDice = app.playerArray(app.currentPlayer).numDiceLeft;
            rolls = randi(6, 1, numDice);

            % save rolls to player
            app.playerArray(app.currentPlayer).currentDice = rolls;

            % dice rolling animation, skipped if option is checked
            % rolling slows down to simulate real dice
            [y,fs]=audioread('diceRollSound.mp3');
            if ~app.DisableAnimations_Checkbox.Value
                app.DisableAnimations_Checkbox.Enable = 'off';
  
                for i = 1:99
                    sound(y,fs);
                    if i < 70
                        pause(0.02);
                    elseif i < 95
                        pause(.0092*i - .624);
                    else
                        pause(.15*i - 14);
                    end
                    
                    % displays a random number on dice
                    for j = 1:numDice
                        num = randi(6);
                        app.dice(j).ImageSource = app.diceImages(num);
                    end
                end
                pause(1);
            end

            % display final rolls
            for i = 1:numDice
                app.dice(i).ImageSource = app.diceImages(rolls(i));
            end

            app.DisableAnimations_Checkbox.Enable = 'on';
            enableDiceSelection(app);
        end

        % save dice button pushed
        function saveDice(app, ~, ~, ~)
            toggleComponent(app, 'button', 12, 2); % save dice INACTIVE
            disableDiceSelection(app);

            % get player data
            currentHand = app.playerArray(app.currentPlayer).currentDice;
            numDiceLeft = app.playerArray(app.currentPlayer).numDiceLeft;
            
            % find values of dice to save with logical mask
            diceToSave = currentHand(app.diceStatus);
            numDiceLeft = numDiceLeft - length(diceToSave);

            % update player data
            app.playerArray(app.currentPlayer).savedDice = diceToSave;
            app.playerArray(app.currentPlayer).numDiceLeft = numDiceLeft;

            % reset dice
            app.diceStatus = false(1, 6);
            for i = 1:6
                app.dice(i).ImageSource = app.diceImages(i);
            end

            toggleComponent(app, 'button', 11, 1); % roll dice OFF

            % if there are no more dice to roll, end the current turn
            if numDiceLeft == 0
                % save final hand to player record
                finalHand = app.playerArray(app.currentPlayer).savedDice;
                app.playerArray(app.currentPlayer).savedTurns(app.currentRound, :) = finalHand;

                % calculate score, save it to record, and update scoreboard
                score = app.calculateScore(finalHand);
                app.roundScores(app.currentRound, app.currentPlayer) = score;
                app.ScoreTable.Data = app.roundScores;
                
                % reset data and components, and move to next player
                endTurn(app);

            % turn continues if there are any dice left to roll
            else
                % update saved dice display
                app.SavedDiceTable.Data = app.playerArray(app.currentPlayer).savedDice;

                % resize gameboard
                for i = app.playerArray(app.currentPlayer).numDiceLeft+1:6
                    app.dice(i).Visible = 'off';
                end
            end
        end

        % ends turn
        function endTurn(app)
            if app.currentRound == app.numRounds && app.currentPlayer == app.numPlayers
                endGame(app);
            else
                % update other players
                % first write data of all saved dice
                thingSpeakWrite(app.channelIDDice,...
                    [app.playerID,...
                    app.playerArray(app.playerID).savedDice(1),...
                    app.playerArray(app.playerID).savedDice(2),...
                    app.playerArray(app.playerID).savedDice(3),...
                    app.playerArray(app.playerID).savedDice(4),...
                    app.playerArray(app.playerID).savedDice(5),...
                    app.playerArray(app.playerID).savedDice(6)],...
                    'WriteKey', app.playerArray(app.playerID).writeKeyDice);

                % reset player value to defaults
                app.playerArray(app.currentPlayer).numDiceLeft = -1;
                app.playerArray(app.currentPlayer).savedDice = -1;
    
                app.currentPlayer = nextPlayer(app);
    
                % move gameboard and buttons
                app.GameBoard.Position = app.boardPositions(app.currentPlayer, :);
                app.buttons(11).Position = app.rollDiceButtonPositions(app.currentPlayer, :);
                app.buttons(12).Position = app.saveDiceButtonPositions(app.currentPlayer, :);
    
                updatePlayerLabels(app);
                resetComponents(app);

                if app.AIMode && app.isAI(app.currentPlayer)
                    rollDiceAI(app);
                end
            end
        end

        % finds out who the next player is
        function playerID = nextPlayer(app)
            if app.AIMode
                 if app.currentPlayer == 4
                    playerID = 1;
                    app.currentRound = app.currentRound + 1;
                 else 
                    playerID = app.currentPlayer + 1;
                 end
            else
                % update current player to next player
                if app.currentPlayer == app.numPlayers
                    playerID = 1;
                    app.currentRound = app.currentRound + 1;
                else 
                    playerID = app.currentPlayer + 1;
                end
            end
        end

        % ends game and moves to ending screen
        function endGame(app)
            % ending screen
            app.LeaderBoard.Visible = 'on';
            changeScene(app, 'game', 'end');
            
            for i = 1:app.numPlayers
                app.finalScore(i) = sum(app.roundScores(:, i));
            end

            for i = 1:app.numPlayers
                [score, index] = max(app.finalScore);
                app.labels(3, i).Text = app.playerArray(index(1)).playerName;
                app.labels(4, i).Text = int2str(score(1));
                app.finalScore(index) = -1;
            end
        end
    end

    %% AI behavior
    methods
        function rollDiceAI(app)
            rollDice(app);
            for k = 1:6
                pause(.01);
                diceClicked(app, app.dice(k), true, k);
            end
            saveDice(app);
            pause(.1);
        end
    end

    %% other custom component callbacks
    methods
        % player clicked on a cell on scoreboard
        function expandScoreInformation(app)
            selection = app.ScoreTable.Selection;
            round = selection(1);
            player = selection(2);
            data = app.playerArray(player).savedTurns(round, :);

            app.DetailedRollDisplay.Data = data;
        end
    end

    %% static functions
    methods (Static)
        % generate player ID
        function ID = generatePlayerID()
            ID = randi(99999);
        end  

        % calculate score
        function score = calculateScore(finalHand)
            score = 0;
            if ismember(1, finalHand) && ismember(4, finalHand)
                score = sum(finalHand) - 5;
            end
        end
    end

    %% dev mode
    properties
%         devMode = false;    
        hostTest = 0;
        playerTest = 1;

        % 1 = skip start    - to options
        % 2 = skip options  - to name
        % 3 = skip name     - to tutorial
        % 4 = skip tutorial - to game
%         skipTo = 1;
%         disableAnimations = true;
    end

    methods
%         function startDevMode(app)
%             if app.disableAnimations
%                 app.DisableAnimations_Checkbox.Value = true;
%             end
% 
%             if app.skipTo >= 1
%                 app.currentPlayer = 1;
%                 changeScene(app, 'start', 'options');
%             end

%             if app.skipTo >= 2
%                 app.gameID = 1;
%                 app.numPlayers = 4;
%                 app.numRounds = 10;
%                 changeScene(app, 'options', 'name');
%             end
% 
%             if app.skipTo >= 3
%                 changeScene(app, 'name', 'tutorial');
%             end
%         end

        function initHost(app)
            hostGame(app, true, true);
            confirmOptions(app, true, true, true);
            hostReady(app, true, true, true)
        end

        function initPlayer(app)
            joinGame(app, true, true);
            confirmGameID(app, true, true);
            playerReady(app, true, true);
        end
    end


%             testSpeed = [.5 .25 .1];

%             pause(2);
%             hostLocalGame(app, true, true, true);
            
%             pause(testSpeed(1)); % click join game button
%             joinGame(app);
            
%             pause(testSpeed(2)); % click cancel button
%             cancelJoin(app);

%             pause(testSpeed(2)); % join again and try to enter correct game ID
%             joinGame(app);
%             pause(testSpeed(2)); % set game ID
%             app.EnterGameID_TextField.Value = 22526;
%             pause(testSpeed(2)); % confirm game ID
%             confirmGameID(app);
            
%             pause(testSpeed(1));
%             confirmOptions(app, true, true, true);
%             app.numPlayers = 4;
%             app.numRounds = 10;
%             app.currentPlayer = 1;

%             pause(testSpeed(1)); % enter name
%             app.EnterName_TextField.Value = "Tester";
%             pause(testSpeed(1)); % submit name
%             SubmitName_ButtonPushed(app, true);

%             app.DisableAnimations_Checkbox.Value = true;
%             for i = 1:app.numRounds
%                 for j = 1:app.numPlayers
%                     rollDice(app);
%                     for k = 1:6
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(k), true, k);
%                     end
%                     saveDice(app)
%                     pause(testSpeed(2));
%                 end
%             end
            
%             pause(testSpeed(1)) % testing different methods of playing 
%             for test = 1:4
%                 pause(testSpeed(1));
%                 switch test
%                     case 1 % selecting and saving all dice in one turn, 
%                         rollDice(app); % testing animation as well
%                         pause(testSpeed(2));
%                         for i = 1:6 
%                             pause(testSpeed(3));
%                             diceClicked(app, app.dice(i), i);
%                         end
%                         pause(testSpeed(1)); % save dice
%                         saveDice(app)
%                         app.DisableAnimations_Checkbox.Value = true;
%                     case 2 % saving only one dice at a time
%                         pause(testSpeed(3));
%                         for i = 6:-1:1
%                             rollDice(app);
%                             pause(testSpeed(3));
%                             diceClicked(app, app.dice(i), i);
%                             pause(testSpeed(3));
%                             saveDice(app);
%                             pause(testSpeed(3));
%                         end
%                     case 3
%                         pause(testSpeed(3)); % spam clicking dice before saving
%                         rollDice(app);
%                         for i = 1:20 % clicking 20 random dice quickly
%                             pause(testSpeed(3));
%                             click = randi(6);
%                             diceClicked(app, app.dice(click), click);
%                         end
%                         pause(testSpeed(3));
%                         saveDice(app);
%                         pause(testSpeed(2))
%                         rollDice(app);
%                         for i = 1:app.playerArray(app.currentPlayer).numDiceLeft
%                             pause(testSpeed(3)); % save the rest of the dice
%                             diceClicked(app, app.dice(i), i);
%                         end
%                         pause(testSpeed(2));
%                         saveDice(app);
%                     case 4
%                         pause(testSpeed(2));
%                         rollDice(app);
%                         pause(testSpeed(3)); % save 3, 2, and 1 dice
%                         diceClicked(app, app.dice(2), 2);
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(5), 5);
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(6), 6);
%                         pause(testSpeed(3));
%                         saveDice(app);
%                         pause(testSpeed(2));
%                         rollDice(app);
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(2), 2);
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(3), 3);
%                         pause(testSpeed(3));
%                         saveDice(app);
%                         pause(testSpeed(2));
%                         rollDice(app);
%                         pause(testSpeed(3));
%                         diceClicked(app, app.dice(1), 1);
%                         pause(testSpeed(3));
%                         saveDice(app);
%                 end
%             end
% 
%             for i = 1:36 % speedrun the rest of the game to produce results
%                 rollDice(app); % testing animation as well
%                     for j = 1:6 
%                         diceClicked(app, app.dice(j), j);
%                     end
%                     saveDice(app)
%             end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ThingSpeakInitialize(app)
            % build app
            initializeData(app);
            initializeCustomComponents(app);
            initializeComponentProperties(app);
            createComponentArrays(app);
%==================================DEV=====================================
%             if app.devMode
%                 startDevMode(app);
%             end
%             
            if app.hostTest
                initHost(app);
            end

            if app.playerTest
                initPlayer(app);
            end
%==================================DEV=====================================
        end

        % Value changed function: NumPlayers_Dropdown
        function NumPlayers_DropdownValueChanged(app, event)
            toggleComponent(app, 'button', 6, 1); % confirm options ON

            % disable AI players if all seats are full
            app.FillWithAI_Checkbox.Enable = 'on';
            if str2double(app.NumPlayers_Dropdown.Value) == 4
                app.FillWithAI_Checkbox.Enable = 'off';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PlayerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlayerUIFigure = uifigure('Visible', 'off');
            app.PlayerUIFigure.Name = 'Player';
            app.PlayerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PlayerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelBackground_Image = uiimage(app.LeftPanel);
            app.LeftPanelBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.PlayerNum_Label = uilabel(app.LeftPanel);
            app.PlayerNum_Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.End_Panel = uipanel(app.RightPanel);
            app.End_Panel.Enable = 'off';
            app.End_Panel.Visible = 'off';
            app.EndBackground_Image = uiimage(app.End_Panel);
            app.EndBackground_Image.ImageSource = fullfile(pathToMLAPP, 'nightSky_Background.jpg');
            app.Game_Panel = uipanel(app.RightPanel);
            app.Game_Panel.Enable = 'off';
            app.Game_Panel.Visible = 'off';
            app.Game_Panel.Tag = 'gamepanel';
            app.GameBackground_Image = uiimage(app.Game_Panel);
            app.GameBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.DisableAnimations_Checkbox = uicheckbox(app.Game_Panel);
            app.DisableAnimations_Checkbox.Text = '';
            app.DisableAnimations_Checkbox.Value = true;
            app.DisableAnimations_Label = uilabel(app.Game_Panel);
            app.DisableAnimations_Label.Text = 'Disable Animations?';
            app.Tutorial_Panel = uipanel(app.RightPanel);
            app.Tutorial_Panel.Enable = 'off';
            app.Tutorial_Panel.Visible = 'off';
            app.TutorialsBackground_Image = uiimage(app.Tutorial_Panel);
            app.TutorialsBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.Tutorial_Label = uilabel(app.Tutorial_Panel);
            app.Tutorial_Label.Text = {'Begin your turn by rolling all six dice. Then, select at least one dice to set aside. Continue to roll and set aside the remaining dice until there are no more dice left to roll. Play then continues to the next player.'; ''; 'After all players have finished their turns, each player''s score for the round will be totaled. In order to score, you must have set aside a 1 and 4 roll. Your score for the round is the total of all dice values excluding the 1 and 4.'; ''; 'Whichever player scores the most points wins. In the case of a tie, all players who scored the highest win.'};
            app.EnterName_Panel = uipanel(app.RightPanel);
            app.EnterName_Panel.Enable = 'off';
            app.EnterName_Panel.Visible = 'off';
            app.EnterNameBackground_Image = uiimage(app.EnterName_Panel);
            app.EnterNameBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.EnterName_TextField = uieditfield(app.EnterName_Panel, 'text');
            app.EnterName_TextField.CharacterLimits = [0 18];
            app.EnterName_TextField.InputType = 'alphanumerics';
            app.EnterName_TextField.Value = 'playerTest';
            app.Options_Panel = uipanel(app.RightPanel);
            app.Options_Panel.Enable = 'off';
            app.Options_Panel.Visible = 'off';
            app.OptionsBackground_Image = uiimage(app.Options_Panel);
            app.OptionsBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.FillWithAI_Checkbox = uicheckbox(app.Options_Panel);
            app.FillWithAI_Checkbox.Text = '';
            app.FillWithAI_Label = uilabel(app.Options_Panel);
            app.FillWithAI_Label.Text = 'Fill to Four Seats with AI?';
            app.NumRounds_Dropdown = uidropdown(app.Options_Panel);
            app.NumRounds_Dropdown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumRounds_Dropdown.Placeholder = '1';
            app.NumRounds_Dropdown.Value = '10';
            app.NumRounds_Label = uilabel(app.Options_Panel);
            app.NumRounds_Label.Text = 'How Many Rounds Will You Play?';
            app.NumPlayers_Dropdown = uidropdown(app.Options_Panel);
            app.NumPlayers_Dropdown.Items = {'0', '1', '2', '3', '4'};
            app.NumPlayers_Dropdown.ValueChangedFcn = createCallbackFcn(app, @NumPlayers_DropdownValueChanged, true);
            app.NumPlayers_Dropdown.Placeholder = '0';
            app.NumPlayers_Dropdown.Value = '2';
            app.NumPlayers_Label = uilabel(app.Options_Panel);
            app.NumPlayers_Label.Text = 'Select Number of Human Players:';
            app.Title_Label = uilabel(app.Options_Panel);
            app.Title_Label.Text = 'Midnight';
            app.Label = uilabel(app.Options_Panel);
            app.Label.Text = '';
            app.Start_Panel = uipanel(app.RightPanel);
            app.StartBackground_Image = uiimage(app.Start_Panel);
            app.StartBackground_Image.ImageSource = 'nightSky_Background.jpg';
            app.WelcomeText_Label = uilabel(app.Start_Panel);
            app.WelcomeText_Label.Text = 'MIDNIGHT';
            app.EnterGameID_TextField = uieditfield(app.Start_Panel, 'numeric');
            app.EnterGameID_TextField.Limits = [0 99999];
            app.EnterGameID_TextField.ValueDisplayFormat = '%.0f';
            app.EnterGameID_TextField.Enable = 'off';
            app.EnterGameID_TextField.Visible = 'off';
            app.EnterGameID_TextField.Tooltip = {'"The Game ID is provided by the host"'};
            app.EnterGameID_TextField.Value = 12345;
            app.UXFLow = uitree(app.PlayerUIFigure);
            app.Host = uitreenode(app.UXFLow);
            app.Host.Text = 'Host';
            app.hostGame_1 = uitreenode(app.Host);
            app.hostGame_1.Text = '1. Host game button pushed';
            app.setOptions_2 = uitreenode(app.Host);
            app.setOptions_2.Text = '2. Set options screen reached';
            app.setNumPlayers_2_1 = uitreenode(app.setOptions_2);
            app.setNumPlayers_2_1.Text = '2.1. Number of players set';
            app.setNumRounds_2_2 = uitreenode(app.setOptions_2);
            app.setNumRounds_2_2.Text = '2.2. Number of rounds set';
            app.continueToEnterName_2_3 = uitreenode(app.setOptions_2);
            app.continueToEnterName_2_3.Text = '2.3. Go to enter name button pushed';
            app.enterName_3 = uitreenode(app.Host);
            app.enterName_3.Text = '3. Enter name screen reached';
            app.enterName_3_1 = uitreenode(app.enterName_3);
            app.enterName_3_1.Text = '3.1. Host player name set';
            app.writeGameData_3_2 = uitreenode(app.enterName_3);
            app.writeGameData_3_2.Text = '3.2. Write following data to thingspeak:';
            app.hostID_3_2a = uitreenode(app.writeGameData_3_2);
            app.hostID_3_2a.Text = '3.2a. Host ID';
            app.numPlayersNode_3_2b = uitreenode(app.writeGameData_3_2);
            app.numPlayersNode_3_2b.Text = '3.2b. Number of players';
            app.numRoundsNode_3_2c = uitreenode(app.writeGameData_3_2);
            app.numRoundsNode_3_2c.Text = '3.2c. Number of rounds';
            app.hostPlayerNameNode_3_2d = uitreenode(app.writeGameData_3_2);
            app.hostPlayerNameNode_3_2d.Text = '3.2d. Host player name';
            app.gototutorial_3_3 = uitreenode(app.enterName_3);
            app.gototutorial_3_3.Text = '3.3. Go to tutorial button pushed';
            app.tutorial_4 = uitreenode(app.Host);
            app.tutorial_4.NodeData = 'tutorial screen reached';
            app.tutorial_4.Text = '4. Tutorial screen reached';
            app.gameIDDisplayed_4_1 = uitreenode(app.tutorial_4);
            app.gameIDDisplayed_4_1.Text = '4.1. Host is given a game ID';
            app.User = uitreenode(app.UXFLow);
            app.User.Text = 'User';
            app.joinGame_1 = uitreenode(app.User);
            app.joinGame_1.Text = '1. Join game button pushed';
            app.enterGameID_1a = uitreenode(app.joinGame_1);
            app.enterGameID_1a.Text = '1a. Enter a game ID';
            app.invalidGameID_to_1a = uitreenode(app.enterGameID_1a);
            app.invalidGameID_to_1a.Text = 'Invalid game ID: return to 1a';
            app.validGameID_to_2 = uitreenode(app.enterGameID_1a);
            app.validGameID_to_2.Text = 'Valid game ID: continue to 2.';
            app.cancel_1b = uitreenode(app.joinGame_1);
            app.cancel_1b.Text = '1b. Cancel button pressed';
            app.cancelButtonPushed_to_1 = uitreenode(app.cancel_1b);
            app.cancelButtonPushed_to_1.Text = 'Return to 1.';
            app.enterName_2 = uitreenode(app.User);
            app.enterName_2.Text = '2. Enter name screen reached';
            app.Node3_2 = uitreenode(app.User);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.User);
            app.Node4_2.Text = 'Node4';
            app.Node5_2 = uitreenode(app.User);
            app.Node5_2.Text = 'Node5';
            % Show the figure after all components are created
            app.PlayerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayerTest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PlayerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @ThingSpeakInitialize)
            else

                % Focus the running singleton app
                figure(runningApp.PlayerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlayerUIFigure)
        end
    end
end